classdef HistogramView < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        UIAxes      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function sample(comp)
           x = [1 2 3 4 5];
           temp_high = [37 39 46 56 67]; 
           temp_low = [22 24 32 41 50];
           temp_medium = [30 30 40 50 60];
           comp.plotHold(x,{temp_high,temp_low,temp_medium});
           comp.UIAxes.XTickLabels = {'January','February','March','April','May'};
           xlabel(comp.UIAxes,'Mes');
           ylabel(comp.UIAxes,'Temperatura');
           legend(comp.UIAxes,{'Average High','Average Low','Average'},'Location','northwest');
           title(comp.UIAxes,'Temperaturas');  
        end

        function LoadLanguage(comp,lang)
            fplot=lang.FrequencyPlot();
            comp.plotData(fplot);
        end

        function plotData(comp,fplotData)
            comp.plotHold(fplotData.x,fplotData.y);
            if(~isempty(fplotData.XTickLabels))
                comp.UIAxes.XTickLabels =fplotData.XTickLabels;
                comp.UIAxes.XTick=1:size(fplotData.XTickLabels,2);
            end
            title(comp.UIAxes,fplotData.Title);  
            xlabel(comp.UIAxes,fplotData.Xlabel);
            ylabel(comp.UIAxes,fplotData.Ylabel);
            
            if(~isempty(fplotData.Legends))
                legend(comp.UIAxes,fplotData.Legends,'Location','northwest');
            end
        end

        function plot=plotHold(comp,x,ys)
            w=.9;
            if(~iscell(ys))
                bar(comp.UIAxes,x,ys,w);
            else
                ws = .9/size(ys,2);
                hold(comp.UIAxes, 'on');
                for i=1:size(ys,2)
                    y=cell2mat(ys(i));
                    bar(comp.UIAxes,x,y,w);
                    w=w-ws;
                end
                hold(comp.UIAxes, 'off');
            end
            plot=comp.UIAxes;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
           
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 532 367];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            title(comp.UIAxes, 'Title')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end