classdef LanguageView < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                matlab.ui.container.GridLayout
        SalvarButton              matlab.ui.control.Button
        GridLayout4               matlab.ui.container.GridLayout
        GridLayout3_2             matlab.ui.container.GridLayout
        FrecuenciaEditField       matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel  matlab.ui.control.Label
        AgregarButton             matlab.ui.control.Button
        GridLayout3               matlab.ui.container.GridLayout
        EditField                 matlab.ui.control.EditField
        CaractrLabel              matlab.ui.control.Label
        EliminarButton            matlab.ui.control.Button
        CaracterspermitidosLabel  matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
    end

    properties (Access = public)
        Language
        figure
    end
    
    
    methods (Access = public)
        
        function init(comp,Language,figure)
            comp.figure=figure;
            comp.Language=Language;
          
            items={};
            itemsData=[];
            for i=1:size(comp.Language.Alphabet,2)
                letter=comp.Language.Alphabet(i);
                items{end+1}=letter.PrintFormat();
                itemsData=[itemsData;letter];
            end
            if(~isempty(items))
                comp.ListBox.Items=items;
                comp.ListBox.ItemsData=itemsData;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(comp, event)
            items=comp.ListBox.Items;
            itemsData=comp.ListBox.ItemsData;
            value=comp.ListBox.Value;
            if(isempty(value))
                return;
            end
            index=value.Index;
            items(index)=[];
            itemsData(index)=[];
            if(isempty(items))
                items={};
                itemsData={};
            end
            comp.ListBox.Items=items;
            comp.ListBox.ItemsData=itemsData;

            comp.Language.Alphabet=itemsData;
            comp.Language.Reindex();
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(comp, event)
            letter=upper(strtrim(comp.EditField.Value));
            if(isempty(letter)||comp.Language.indexOf(letter)>0)
                return;
            end
            items=comp.ListBox.Items;
            itemsData=comp.ListBox.ItemsData;
            character=LanguageCharacter(size(itemsData,2)+1,letter,comp.FrecuenciaEditField.Value);
            items{end+1}=character.PrintFormat();
            if(iscell(itemsData))
                itemsData{end+1}=character;
            end
            if(isvector(itemsData))
                itemsData(end+1)=character;
            end
            comp.ListBox.Items=items;
            comp.ListBox.ItemsData=itemsData;
            comp.EditField.Value='';
            comp.FrecuenciaEditField.Value=0;
            comp.Language.Alphabet=itemsData;
            comp.Language.Reindex();
        end

        % Button pushed function: SalvarButton
        function SalvarButtonPushed(comp, event)
            comp.Language.Alphabet=comp.ListBox.ItemsData;
            comp.Language.Reindex();
            comp.Language.Save();
            comp.figure.Reinit();
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(comp, event)
            value = comp.ListBox.Value;
            comp.FrecuenciaEditField.Value=value.StandardFrecuency;
            comp.EditField.Value=value.Letter;
        end

        % Value changed function: FrecuenciaEditField
        function FrecuenciaEditFieldValueChanged(comp, event)
            selectedLetter=comp.ListBox.Value;
            if(comp.EditField.Value==selectedLetter.Letter)
                value = comp.FrecuenciaEditField.Value;
                selectedLetter.StandardFrecuency=value;
                comp.ListBox.Items(selectedLetter.Index)=cellstr(selectedLetter.PrintFormat());
                comp.ListBox.ItemsData(selectedLetter.Index)=selectedLetter;
            end
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(comp, event)
            value =upper(event.Value);
            if(size(value,2)>1)
                value=value(1);
            end
            index=comp.Language.indexOf(value);
            if(index>0)
                comp.ListBox.Value=comp.ListBox.ItemsData(index);
                ListBoxValueChanged(comp,event);
            end
            comp.EditField.Value=value;
        end

        % Value changing function: EditField
        function EditFieldValueChanging(comp, event)
            changingValue =upper(event.Value);
            if(size(changingValue)>1)
                comp.EditField.Value=changingValue(1);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [0 0 505 361];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.ListBox = uilistbox(comp.GridLayout);
            comp.ListBox.Items = {};
            comp.ListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ListBoxValueChanged, true);
            comp.ListBox.Value = {};
            comp.CaracterspermitidosLabel = uilabel(comp.GridLayout);
            comp.CaracterspermitidosLabel.Text = 'Caracterés permitidos';
            comp.GridLayout4 = uigridlayout(comp.GridLayout);
            comp.EliminarButton = uibutton(comp.GridLayout4, 'push');
            comp.EliminarButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @EliminarButtonPushed, true);
            comp.EliminarButton.Text = 'Eliminar';
            comp.GridLayout3 = uigridlayout(comp.GridLayout4);
            comp.CaractrLabel = uilabel(comp.GridLayout3);
            comp.CaractrLabel.Text = 'Caractér:';
            comp.EditField = uieditfield(comp.GridLayout3, 'text');
            comp.EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditFieldValueChanged, true);
            comp.EditField.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @EditFieldValueChanging, true);
            comp.AgregarButton = uibutton(comp.GridLayout4, 'push');
            comp.AgregarButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AgregarButtonPushed, true);
            comp.AgregarButton.Text = 'Agregar';
            comp.GridLayout3_2 = uigridlayout(comp.GridLayout4);
            comp.FrecuenciaEditFieldLabel = uilabel(comp.GridLayout3_2);
            comp.FrecuenciaEditFieldLabel.Text = 'Frecuencia:';
            comp.FrecuenciaEditField = uieditfield(comp.GridLayout3_2, 'numeric');
            comp.FrecuenciaEditField.Limits = [0 100];
            comp.FrecuenciaEditField.ValueDisplayFormat = '%5.2f%%';
            comp.FrecuenciaEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FrecuenciaEditFieldValueChanged, true);
            comp.SalvarButton = uibutton(comp.GridLayout, 'push');
            comp.SalvarButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SalvarButtonPushed, true);
            comp.SalvarButton.Text = 'Salvar';
        end
    end
end