classdef LanguagesView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IdiomasUIFigure   matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        GridLayout2       matlab.ui.container.GridLayout
        GridLayout4       matlab.ui.container.GridLayout
        GridLayout3       matlab.ui.container.GridLayout
        HistogramaButton  matlab.ui.control.Button
        EditField         matlab.ui.control.EditField
        NuevoButton       matlab.ui.control.Button
        EliminarButton    matlab.ui.control.Button
        DropDown          matlab.ui.control.DropDown
        IdiomaLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        LanguagesManager % Description
        Parent
    end
    
    methods (Access = public)
        function Reinit(app)
            app.init(app.LanguagesManager,app.Parent);
        end
        function init(app,languagesManager,parent)
            app.Parent=parent;

            a=app.GridLayout4.Children;
            delete(a);
            items=[];
            itemsData=[];
            app.LanguagesManager=languagesManager;
            for i=1:size(app.LanguagesManager.Languages,1)
                lang=app.LanguagesManager.Languages(i);
                items{end+1}=lang.Name;
                itemsData{end+1}=lang;
            end
            app.DropDown.Items=items;
            app.DropDown.ItemsData=itemsData;
            lang=app.LanguagesManager.Languages(1);
            langview=LanguageView(app.GridLayout4);
            langview.init(lang,app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            a=app.GridLayout4.Children;
            delete(a);
            langview=LanguageView(app.GridLayout4);
            langview.init(value,app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value =upper(strtrim(app.EditField.Value));
            app.EditField.Value=value;
        end

        % Button pushed function: NuevoButton
        function NuevoButtonPushed(app, event)
            name=app.EditField.Value;
            if(isempty(name))
                return;
            end
            newlang=LanguageDefinition(size(app.LanguagesManager.Languages)+1,name,{});
            app.LanguagesManager.Languages=[app.LanguagesManager.Languages;newlang];
            app.init(app.LanguagesManager,app.Parent);
            app.EditField.Value='';
            app.DropDown.Value=newlang;
            DropDownValueChanged(app,event);
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            lang=app.DropDown.Value;
            index=lang.Index;
            if(size(index)<=0)
                return;
            end
            lang.Delete();
            app.LanguagesManager.RemoveAt(index);
            app.init(app.LanguagesManager,app.Parent);
        end

        % Close request function: IdiomasUIFigure
        function IdiomasUIFigureCloseRequest(app, event)
            parent=app.Parent;
            delete(app)       
            parent.init();
        end

        % Button pushed function: HistogramaButton
        function HistogramaButtonPushed(app, event)
            tools=helper;
            histogram=HistogramView;
            histogram.LoadLanguage(app.DropDown.Value);
            tools.centerWindow(histogram,app.Parent.CifradoCesarUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IdiomasUIFigure = uifigure('Visible', 'off');
            app.IdiomasUIFigure.Name = 'Idiomas';
            app.IdiomasUIFigure.CloseRequestFcn = createCallbackFcn(app, @IdiomasUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.IdiomasUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.IdiomaLabel = uilabel(app.GridLayout3);
            app.IdiomaLabel.Text = 'Idioma:';
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.EliminarButton = uibutton(app.GridLayout3, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.NuevoButton = uibutton(app.GridLayout3, 'push');
            app.NuevoButton.ButtonPushedFcn = createCallbackFcn(app, @NuevoButtonPushed, true);
            app.NuevoButton.Text = 'Nuevo';
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.HistogramaButton = uibutton(app.GridLayout3, 'push');
            app.HistogramaButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaButtonPushed, true);
            app.HistogramaButton.Text = 'Histograma';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            % Show the figure after all components are created
            app.IdiomasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LanguagesView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IdiomasUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IdiomasUIFigure)
        end
    end
end