classdef NumericKeyView < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout5        matlab.ui.container.GridLayout
        LlaveSpinner       matlab.ui.control.Spinner
        LlaveSpinnerLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        maxKey
        minKey
    end
    
    
    methods (Access = public)       
        function key = GetKey(comp)
            key=comp.LlaveSpinner.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LlaveSpinner
        function LlaveSpinnerValueChanged(comp, event)
            value = comp.LlaveSpinner.Value;
            if(value>=comp.maxKey)
                msgbox("La llave debe ser menor que el tamaño del diccionario.","Error","error","modal");
                comp.LlaveSpinner.Value=event.PreviousValue;
                return;
            end
            if(value<=comp.minKey)
                msgbox("La llave debe ser mayor que cero.","Error","error","modal");
                comp.LlaveSpinner.Value=event.PreviousValue;
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [0 0 320 240];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout5 = uigridlayout(comp);
            comp.LlaveSpinnerLabel = uilabel(comp.GridLayout5);
            comp.LlaveSpinnerLabel.Text = 'Llave:';
            comp.LlaveSpinner = uispinner(comp.GridLayout5);
            comp.LlaveSpinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LlaveSpinnerValueChanged, true);
            comp.LlaveSpinner.Value = 1;
        end
    end
end