classdef TextKeyView < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout5          matlab.ui.container.GridLayout
        LlaveEditField       matlab.ui.control.EditField
        LlaveEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        language
    end
    
    
    methods (Access = public)

        function key = GetKey(comp)
            key=comp.LlaveEditField.Value;
        end

        function validateKey(comp,language)    
            comp.language=language;
            key = comp.LlaveEditField.Value;
            for i=1:size(key,2)
                letter=key(i); %obtener el caractér en la posición i
                letter=language.normalizeChar(letter);% normalizar el texto (puede que no este normalizado)
                if(letter<=0)
                     msgbox("La llave de cifrado no es valida caractér no valido:["+key(i)+"]","Error","error","modal");
                     comp.LlaveEditField.Value='';
                     return;
                end
                key(i)=letter;
            end
            comp.LlaveEditField.Value=key;
        end

        function result=isNotEmpty(comp)
            result=~isempty(comp.LlaveEditField.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LlaveEditField
        function LlaveEditFieldValueChanged(comp, event)
            comp.validateKey(comp.language);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [0 0 339 66];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout5 = uigridlayout(comp);
            comp.LlaveEditFieldLabel = uilabel(comp.GridLayout5);
            comp.LlaveEditFieldLabel.Text = 'Llave:';
            comp.LlaveEditField = uieditfield(comp.GridLayout5, 'text');
            comp.LlaveEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LlaveEditFieldValueChanged, true);
        end
    end
end