classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CifradoCesarUIFigure            matlab.ui.Figure
        ArchivoMenu                     matlab.ui.container.Menu
        AbrirMenu                       matlab.ui.container.Menu
        AcercadeMenu                    matlab.ui.container.Menu
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        CifradoTab                      matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout                      matlab.ui.container.GridLayout
        LlavededesplazamientoSpinner    matlab.ui.control.Spinner
        LlavededesplazamientoSpinnerLabel  matlab.ui.control.Label
        TextoTextArea                   matlab.ui.control.TextArea
        TextoTextAreaLabel              matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        FiltrarcarcterespecialesCheckBox  matlab.ui.control.CheckBox
        LimpiarButton                   matlab.ui.control.Button
        CifrarButton                    matlab.ui.control.Button
        DescifradoTab                   matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        GridLayout_2                    matlab.ui.container.GridLayout
        LlavededesplazamientoSpinner_2  matlab.ui.control.Spinner
        LlavededesplazamientoSpinner_2Label  matlab.ui.control.Label
        TextoTextArea_2                 matlab.ui.control.TextArea
        TextoTextArea_2Label            matlab.ui.control.Label
        GridLayout4_2                   matlab.ui.container.GridLayout
        LimpiarButton_2                 matlab.ui.control.Button
        DescifrarButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            clear
        end

        % Menu selected function: AbrirMenu
        function AbrirMenuSelected(app, event)
            %Mostrar una ventana para seleccionar archivo filtrado .txt
            [file,path] = uigetfile('*.txt');
            %Si se selecciono un archivo
            if ~(isequal(file,0))
                %Obtener ruta completa del archivo seleccionado
                fullpath=fullfile(path,file);

                %instancia un objeto de la clase ProgressBar que esta en el
                %archivo ProgressBar.m
                pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
                %Mostrar barra de progeso
                pgrssbar.show('Leyendo archivo...');
                
                %instancia de clase helper
                help =helper;
                %leer archivo seleccionado y filtrar o no los caracterés
                data=help.readFile(fullpath,app.FiltrarcarcterespecialesCheckBox.Value);
            
                %Mostar el texto leido en
                %el textarea que este seleccionado segun el TabGroup y
                %limpiar el textarea que no esta seleccionado
                if(app.TabGroup.SelectedTab.Title=="Cifrado")
                    app.TextoTextArea_2.Value='';
                    app.TextoTextArea.Value=string(data); 
                else
                    app.TextoTextArea.Value='';
                    app.TextoTextArea_2.Value=string(data); 
                end
                

                pgrssbar.close();
            end            
        end

        % Button pushed function: LimpiarButton, LimpiarButton_2
        function LimpiarButtonPushed(app, event)
             %limpiar los textareas que esta seleccionado segun el TabGroup
             app.TextoTextArea.Value=''; 
             app.TextoTextArea_2.Value='';
        end

        % Button pushed function: DescifrarButton
        function DescifrarButtonPushed(app, event)
            %Obtener el desplazamiento de la llave del control spinner
            keyOffset=app.LlavededesplazamientoSpinner.Value;
            
            %instancia un objeto de la clase ProgressBar que esta en el
            %archivo ProgressBar.m
            pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
            %Mostrar barra de progeso
            pgrssbar.show('Descifrando texto...');

            %instancia un objeto de la clase CesarChiper que esta en el
            %archivo CesarChiper.m
            cesar=CesarCipher;
            %asigna el valor del textarea decifrado al resultado de la función
            %'dechiper' que se encarga de decifrar el texto del textarea
            %cifrado y le envia el offset de la llave seleccionada
            app.TextoTextArea.Value=cesar.decipher(app.TextoTextArea_2.Value,keyOffset,app.FiltrarcarcterespecialesCheckBox.Value);
            %Cambia la vista al textarea con el contenido decifrado
            app.TabGroup.SelectedTab=app.CifradoTab;
            pgrssbar.close();
        end

        % Button pushed function: CifrarButton
        function CifrarButtonPushed(app, event)
           %Obtener el desplazamiento de la llave del control spinner
           keyOffset=app.LlavededesplazamientoSpinner.Value;
           
           %instancia un objeto de la clase ProgressBar que esta en el
           %archivo ProgressBar.m
           pgrssbar=ProgressBar(1,app.CifradoCesarUIFigure);
           %Mostrar barra de progeso
           pgrssbar.show('Cifrando texto...');


           %instancia un objeto de la clase CesarChiper que esta en el
           %archivo CesarChiper.m
           cesar=CesarCipher;
           %asigna el valor del textarea cifrado al resultado de la función
           %'chiper' que se encarga de cifrar el texto del textarea
           %decifrado y le envia el offset de la llave seleccionada
           app.TextoTextArea_2.Value=cesar.cipher(app.TextoTextArea.Value,keyOffset,app.FiltrarcarcterespecialesCheckBox.Value);
           %Cambia la vista al textarea con el contenido cifrado
           app.TabGroup.SelectedTab=app.DescifradoTab;
           pgrssbar.close(); %Cerar la vetana de espera
        end

        % Value changed function: LlavededesplazamientoSpinner, 
        % ...and 1 other component
        function LlavededesplazamientoSpinnerValueChanged(app, event)
            %Obtener el spinner que provoco este evento y su nuevo valor
            value=event.Source.Value;
            %Asignar el mismo valor a ambos spinners para mantenerlos a la
            %par
            app.LlavededesplazamientoSpinner.Value=value;
            app.LlavededesplazamientoSpinner_2.Value=value;
        end

        % Menu selected function: AcercadeMenu
        function AcercadeMenuSelected(app, event)
            %Instancia de la clase helper
            jhelper=helper;
            %Mostrar y centrar la ventana about
            jhelper.centerWindow(about.CifradoCesarUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CifradoCesarUIFigure = uifigure('Visible', 'off');
            app.CifradoCesarUIFigure.Name = 'Cifrado Cesar';
            app.ArchivoMenu = uimenu(app.CifradoCesarUIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.AbrirMenu = uimenu(app.ArchivoMenu);
            app.AbrirMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirMenuSelected, true);
            app.AbrirMenu.Text = 'Abrir';
            app.AcercadeMenu = uimenu(app.CifradoCesarUIFigure);
            app.AcercadeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadeMenuSelected, true);
            app.AcercadeMenu.Text = 'Acerca de...';
            app.GridLayout2 = uigridlayout(app.CifradoCesarUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.CifradoTab = uitab(app.TabGroup);
            app.CifradoTab.Title = 'Cifrado';
            app.GridLayout3 = uigridlayout(app.CifradoTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.CifrarButton = uibutton(app.GridLayout4, 'push');
            app.CifrarButton.ButtonPushedFcn = createCallbackFcn(app, @CifrarButtonPushed, true);
            app.CifrarButton.Text = 'Cifrar';
            app.LimpiarButton = uibutton(app.GridLayout4, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.FiltrarcarcterespecialesCheckBox = uicheckbox(app.GridLayout4);
            app.FiltrarcarcterespecialesCheckBox.Text = 'Filtrar carácter especiales.';
            app.GridLayout = uigridlayout(app.GridLayout3);
            app.TextoTextAreaLabel = uilabel(app.GridLayout);
            app.TextoTextAreaLabel.Text = 'Texto';
            app.TextoTextArea = uitextarea(app.GridLayout);
            app.LlavededesplazamientoSpinnerLabel = uilabel(app.GridLayout);
            app.LlavededesplazamientoSpinnerLabel.Text = 'Llave de desplazamiento:';
            app.LlavededesplazamientoSpinner = uispinner(app.GridLayout);
            app.LlavededesplazamientoSpinner.ValueChangedFcn = createCallbackFcn(app, @LlavededesplazamientoSpinnerValueChanged, true);
            app.LlavededesplazamientoSpinner.Value = 777;
            app.DescifradoTab = uitab(app.TabGroup);
            app.DescifradoTab.Title = 'Descifrado';
            app.GridLayout3_2 = uigridlayout(app.DescifradoTab);
            app.GridLayout4_2 = uigridlayout(app.GridLayout3_2);
            app.DescifrarButton = uibutton(app.GridLayout4_2, 'push');
            app.DescifrarButton.ButtonPushedFcn = createCallbackFcn(app, @DescifrarButtonPushed, true);
            app.DescifrarButton.Text = 'Descifrar';
            app.LimpiarButton_2 = uibutton(app.GridLayout4_2, 'push');
            app.LimpiarButton_2.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton_2.Text = 'Limpiar';
            app.GridLayout_2 = uigridlayout(app.GridLayout3_2);
            app.TextoTextArea_2Label = uilabel(app.GridLayout_2);
            app.TextoTextArea_2Label.Text = 'Texto';
            app.TextoTextArea_2 = uitextarea(app.GridLayout_2);
            app.LlavededesplazamientoSpinner_2Label = uilabel(app.GridLayout_2);
            app.LlavededesplazamientoSpinner_2Label.Text = 'Llave de desplazamiento:';
            app.LlavededesplazamientoSpinner_2 = uispinner(app.GridLayout_2);
            app.LlavededesplazamientoSpinner_2.ValueChangedFcn = createCallbackFcn(app, @LlavededesplazamientoSpinnerValueChanged, true);
            app.LlavededesplazamientoSpinner_2.Value = 777;
            % Show the figure after all components are created
            app.CifradoCesarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CifradoCesarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CifradoCesarUIFigure)
        end
    end
end